classdef CombatTracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CombAlvaTrackedUIFigure      matlab.ui.Figure
        HomeMenu                     matlab.ui.container.Menu
        NewMenu                      matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        CharactersInfoTab            matlab.ui.container.Tab
        CheckBox                     matlab.ui.control.CheckBox
        StartCombatButton            matlab.ui.control.Button
        UndoCharacterButton          matlab.ui.control.Button
        ConditionDropDown            matlab.ui.control.DropDown
        ConditionDropDownLabel       matlab.ui.control.Label
        InitiativeEditField          matlab.ui.control.NumericEditField
        InitiativeEditFieldLabel     matlab.ui.control.Label
        ACEditField                  matlab.ui.control.NumericEditField
        ACEditFieldLabel             matlab.ui.control.Label
        HPEditField                  matlab.ui.control.NumericEditField
        HPEditFieldLabel             matlab.ui.control.Label
        CharacterEditField           matlab.ui.control.EditField
        CharacterEditFieldLabel      matlab.ui.control.Label
        AddCharacterButton           matlab.ui.control.Button
        UITable2                     matlab.ui.control.Table
        RoundsTab                    matlab.ui.container.Tab
        DealActionButton             matlab.ui.control.Button
        HealtargetDropDown           matlab.ui.control.DropDown
        HealtargetDropDownLabel      matlab.ui.control.Label
        DamagehealedEditField        matlab.ui.control.NumericEditField
        DamagehealedEditFieldLabel   matlab.ui.control.Label
        HealLabel                    matlab.ui.control.Label
        ConditionLabel               matlab.ui.control.Label
        DamageLabel                  matlab.ui.control.Label
        TargetDropDown_2             matlab.ui.control.DropDown
        TargetDropDown_2Label        matlab.ui.control.Label
        ConditiondealtDropDown       matlab.ui.control.DropDown
        ConditiondealtDropDownLabel  matlab.ui.control.Label
        RoundEditField               matlab.ui.control.NumericEditField
        RoundEditFieldLabel          matlab.ui.control.Label
        DamagetargetDropDown         matlab.ui.control.DropDown
        DamagetargetDropDownLabel    matlab.ui.control.Label
        DamagedealtEditField         matlab.ui.control.NumericEditField
        DamagedealtEditFieldLabel    matlab.ui.control.Label
        NextTurnButton               matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
    end

    
    properties (Access = private)
        turn = 0;
        round = 0;

        cha = []; %nomes dos personagens
        hp = []; 
        permadeth = []; %hp necessaria pra dar perma death
        ac = [];
        init = []; %iniciativa dos personagens
        cond = []; %condição dos personagens

        i = 0;
        testi = 0;
        testcond = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddCharacterButton
        function AddCharacterButtonPushed(app, event)
            app.i = app.i + 1;

            app.cha(app.i).text = app.CharacterEditField.Value;
            app.hp(app.i) = app.HPEditField.Value;
            app.permadeth(app.i) = 0 - app.hp(app.i);
            app.ac(app.i) = app.ACEditField.Value;
            app.init(app.i) = app.InitiativeEditField.Value;
            app.cond(app.i).text = app.ConditionDropDown.Value;

            cu = {app.cha(app.i).text app.hp(app.i) app.ac(app.i) app.init(app.i) app.cond(app.i).text};

            app.UITable2.Data = [app.UITable2.Data; cu];

            app.CharacterEditField.Value = "";
            app.HPEditField.Value = 0;
            app.ACEditField.Value = 0;
            app.InitiativeEditField.Value = 0;
            app.ConditionDropDown.Value = "Normal";

        end

        % Button pushed function: UndoCharacterButton
        function UndoCharacterButtonPushed(app, event)
            if app.i > 0
                app.UITable2.Data(app.i, :) = [];

                app.i = app.i - 1;

                if app.i == 0
                    app.CheckBox.Value = 0;
                end
            end
        end

        % Button pushed function: StartCombatButton
        function StartCombatButtonPushed(app, event)
            ClearInitiativeTableButtonPushed(app, event);

            for y = 1: +1: app.i
                for t = 2: +1: app.i
                    if app.init(t) > app.init(t-1)
                        chatemp.text = app.cha(t-1).text;
                        hptemp = app.hp(t-1);
                        permadethtemp = app.permadeth(t-1);
                        actemp = app.ac(t-1);
                        inittemp = app.init(t-1);
                        contemp.text = app.cond(t-1).text;

                        app.cha(t-1).text = app.cha(t).text;
                        app.hp(t-1) = app.hp(t);
                        app.permadeth(t-1) = app.permadeth(t);
                        app.ac(t-1) = app.ac(t);
                        app.init(t-1) = app.init(t);
                        app.cond(t-1).text = app.cond(t).text;

                        app.cha(t).text = chatemp.text;
                        app.hp(t) = hptemp;
                        app.permadeth(t) = permadethtemp;
                        app.ac(t) = actemp;
                        app.init(t) = inittemp;
                        app.cond(t).text = contemp.text;
                    end
                end
            end

            for y = 1: +1: app.i
                cu = {app.cha(y).text app.hp(y) app.ac(y) app.init(y) app.cond(y).text};
    
                app.UITable.Data = [app.UITable.Data; cu];
                if app.cond(y).text ~= "Normal"
                    addStyle(app.UITable, uistyle("FontColor", [0.9290 0.6940 0.1250]), "row", y);
                end
            end

            app.CheckBox.Value = 1;

            chasup = {};
            for y = 1: +1: app.i
                chasup = cat(2,chasup, app.cha(y).text);
            end

            app.HealtargetDropDown.Items = chasup;
            app.TargetDropDown_2.Items = chasup;
            app.DamagetargetDropDown.Items = chasup;

            app.testi = app.i;

            app.turn = 1;
            addStyle(app.UITable, uistyle("BackgroundColor", [0 1 1]), "row", app.turn);
            app.round = 1;
            app.RoundEditField.Value = app.round;

        end

        % Callback function
        function ClearInitiativeTableButtonPushed(app, event)
            if app.testi > 0
                cu = app.testi;

                for y = 1: +1: cu
                    app.UITable.Data(app.testi, :) = [];
    
                    app.testi = app.testi - 1;

                    if rem(y, 2) > 0
                        addStyle(app.UITable, uistyle("BackgroundColor", [1 1 1]), "row", y);
                        addStyle(app.UITable, uistyle("FontColor", [0 0 0]), "row", y);
                    elseif rem(y, 2) == 0
                        addStyle(app.UITable, uistyle("BackgroundColor", [0.94 0.94 0.94]), "row", y);
                        addStyle(app.UITable, uistyle("FontColor", [0 0 0]), "row", y);
                    end
                end
            end
        end

        % Button pushed function: DealActionButton
        function DealActionButtonPushed(app, event)
            dead = uistyle("FontColor", [1 0 0]);
            stable = uistyle("FontColor", [0 0 1]);
            normal = uistyle("FontColor", [0 0 0]);
            condition = uistyle("FontColor", [0.9290 0.6940 0.1250]);
            
            if app.DamagedealtEditField.Value ~= 0
                for y = 1: +1: app.i
                    if strcmp(app.cha(y).text, app.DamagetargetDropDown.Value)
                        app.hp(y) = app.hp(y) - app.DamagedealtEditField.Value;

                        if app.hp(y) <= 0
                            if app.hp(y) <= app.permadeth(y)
                                app.cond(y).text = 'Perma Dead';
                            else
                                app.cond(y).text = 'Droped/Dead';
                            end
                            addStyle(app.UITable, dead, "row", y);
                        end

                        app.UITable.Data(y, :) = {app.cha(y).text app.hp(y) app.ac(y) app.init(y) app.cond(y).text};
                    end
                end
            end

            if app.DamagehealedEditField.Value ~= 0
                for y = 1: +1: app.i
                    if strcmp(app.cha(y).text, app.HealtargetDropDown.Value)
                        app.hp(y) = app.hp(y) + app.DamagehealedEditField.Value;

                        if app.cond(y).text == "Droped/Dead" && app.hp(y) == 0
                            app.cond(y).text = 'Stable';
                            addStyle(app.UITable, stable, "row", y);

                        elseif (app.cond(y).text == "Droped/Dead" || app.cond(y).text == "Stable") && app.hp(y) > 0
                            app.cond(y).text = 'Normal';
                            addStyle(app.UITable, normal, "row", y);
                        end

                        app.UITable.Data(y, :) = {app.cha(y).text app.hp(y) app.ac(y) app.init(y) app.cond(y).text};
                    end
                end
            end

            if app.testcond
                for y = 1: +1: app.i
                    if strcmp(app.cha(y).text, app.TargetDropDown_2.Value)
                        app.cond(y).text = app.ConditiondealtDropDown.Value;

                        if app.cond(y).text ~= "Normal"
                            addStyle(app.UITable, condition, "row", y);
                        else
                            addStyle(app.UITable, normal, "row", y);
                        end
    
                        app.UITable.Data(y, :) = {app.cha(y).text app.hp(y) app.ac(y) app.init(y) app.cond(y).text};
                    end
                end
            end

            app.testcond = 0;
            app.DamagedealtEditField.Value = 0;
            app.DamagetargetDropDown.Value = app.cha(1).text;
            app.TargetDropDown_2.Value = app.cha(1).text;
            app.ConditiondealtDropDown.Value = "";
            app.DamagehealedEditField.Value = 0;
            app.HealtargetDropDown.Value = app.cha(1).text;
        end

        % Value changed function: ConditiondealtDropDown
        function ConditiondealtDropDownValueChanged(app, event)
            app.testcond = 1;
            
        end

        % Button pushed function: NextTurnButton
        function NextTurnButtonPushed(app, event)
            app.turn = app.turn + 1;
            
            addStyle(app.UITable, uistyle("BackgroundColor", [0 1 1]), "row", app.turn);
            if rem((app.turn - 1), 2) > 0
                addStyle(app.UITable, uistyle("BackgroundColor", [1 1 1]), "row", (app.turn - 1));
            elseif rem((app.turn - 1), 2) == 0
                addStyle(app.UITable, uistyle("BackgroundColor", [0.94 0.94 0.94]), "row", (app.turn - 1));
            end

            if app.turn == (app.i + 1)
                app.round = app.round + 1;
                app.RoundEditField.Value = app.round;
                app.turn = 1;

                addStyle(app.UITable, uistyle("BackgroundColor", [0 1 1]), "row", app.turn);
                if rem(app.i, 2) > 0
                    addStyle(app.UITable, uistyle("BackgroundColor", [1 1 1]), "row", app.i);
                elseif rem(app.i, 2) == 0
                    addStyle(app.UITable, uistyle("BackgroundColor", [0.94 0.94 0.94]), "row", app.i);
                end
            end

            if app.cond(app.turn).text == "Perma Dead"
                NextTurnButtonPushed(app, event);
            end
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            app.CharacterEditField.Value = "";
            app.HPEditField.Value = 0;
            app.ACEditField.Value = 0;
            app.InitiativeEditField.Value = 0;
            app.ConditionDropDown.Value = "Normal";
            app.ConditiondealtDropDown.Value = "Normal";

            if app.i > 0
                for y = 1: +1: app.i
                    if rem(y, 2) > 0
                        addStyle(app.UITable, uistyle("BackgroundColor", [1 1 1]), "row", y);
                        addStyle(app.UITable, uistyle("FontColor", [0 0 0]), "row", y);
                    elseif rem(y, 2) == 0
                        addStyle(app.UITable, uistyle("BackgroundColor", [0.94 0.94 0.94]), "row", y);
                        addStyle(app.UITable, uistyle("FontColor", [0 0 0]), "row", y);
                    end
                end

                cu = app.testi;

                for y = 1: +1: cu
                    app.UITable2.Data(app.i, :) = [];
    
                    app.i = app.i - 1;
                end
            end
            
            if app.testi > 0
                cu = app.testi;

                for y = 1: +1: cu
                    app.UITable.Data(app.testi, :) = [];
    
                    app.testi = app.testi - 1;
                end
            end

            if app.i == 0 && app.testi == 0
                app.CheckBox.Value = 0;
            end

            app.HealtargetDropDown.Items = {};
            app.TargetDropDown_2.Items = {};
            app.DamagetargetDropDown.Items = {};
            app.testcond = 0;
            app.DamagedealtEditField.Value = 0;
            app.ConditiondealtDropDown.Value = "";
            app.DamagehealedEditField.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CombAlvaTrackedUIFigure = uifigure('Visible', 'off');
            app.CombAlvaTrackedUIFigure.Name = 'CombAlva Tracked';
            app.CombAlvaTrackedUIFigure.Icon = 'Logo.jpg';
            app.HomeMenu = uimenu(app.CombAlvaTrackedUIFigure);
            app.HomeMenu.Text = 'Home';
            app.NewMenu = uimenu(app.HomeMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Accelerator = 'N';
            app.NewMenu.Text = 'New';
            app.TabGroup = uitabgroup(app.CombAlvaTrackedUIFigure);
            app.CharactersInfoTab = uitab(app.TabGroup);
            app.CharactersInfoTab.Title = 'Characters Info';
            app.UITable2 = uitable(app.CharactersInfoTab);
            app.UITable2.ColumnName = {'Character'; 'HP'; 'AC'; 'Initiative'; 'Condition'};
            app.UITable2.RowName = {};
            app.AddCharacterButton = uibutton(app.CharactersInfoTab, 'push');
            app.AddCharacterButton.ButtonPushedFcn = createCallbackFcn(app, @AddCharacterButtonPushed, true);
            app.AddCharacterButton.Text = 'Add Character';
            app.CharacterEditFieldLabel = uilabel(app.CharactersInfoTab);
            app.CharacterEditFieldLabel.Text = 'Character';
            app.CharacterEditField = uieditfield(app.CharactersInfoTab, 'text');
            app.HPEditFieldLabel = uilabel(app.CharactersInfoTab);
            app.HPEditFieldLabel.Text = 'HP';
            app.HPEditField = uieditfield(app.CharactersInfoTab, 'numeric');
            app.HPEditField.ValueDisplayFormat = '%.0f';
            app.ACEditFieldLabel = uilabel(app.CharactersInfoTab);
            app.ACEditFieldLabel.Text = 'AC';
            app.ACEditField = uieditfield(app.CharactersInfoTab, 'numeric');
            app.ACEditField.ValueDisplayFormat = '%.0f';
            app.InitiativeEditFieldLabel = uilabel(app.CharactersInfoTab);
            app.InitiativeEditFieldLabel.Text = 'Initiative';
            app.InitiativeEditField = uieditfield(app.CharactersInfoTab, 'numeric');
            app.InitiativeEditField.ValueDisplayFormat = '%.0f';
            app.ConditionDropDownLabel = uilabel(app.CharactersInfoTab);
            app.ConditionDropDownLabel.Text = 'Condition';
            app.ConditionDropDown = uidropdown(app.CharactersInfoTab);
            app.ConditionDropDown.Items = {'Normal', 'Prone', 'Hiden', 'Grappled', 'Charmed', 'Frightened', 'Incapacitated', 'Paralised', 'Petrified', 'Restrained', 'Stunned', 'Unconscious', 'Poisoned', 'Invisible', 'Blind', 'Deafened'};
            app.ConditionDropDown.Value = 'Normal';
            app.UndoCharacterButton = uibutton(app.CharactersInfoTab, 'push');
            app.UndoCharacterButton.ButtonPushedFcn = createCallbackFcn(app, @UndoCharacterButtonPushed, true);
            app.UndoCharacterButton.Text = 'Undo Character';
            app.StartCombatButton = uibutton(app.CharactersInfoTab, 'push');
            app.StartCombatButton.ButtonPushedFcn = createCallbackFcn(app, @StartCombatButtonPushed, true);
            app.StartCombatButton.Text = 'Start Combat';
            app.CheckBox = uicheckbox(app.CharactersInfoTab);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Text = '';
            app.RoundsTab = uitab(app.TabGroup);
            app.RoundsTab.Title = 'Rounds';
            app.UITable = uitable(app.RoundsTab);
            app.UITable.ColumnName = {'Character'; 'HP'; 'AC'; 'Initiative'; 'Condition'};
            app.UITable.RowName = {};
            app.NextTurnButton = uibutton(app.RoundsTab, 'push');
            app.NextTurnButton.ButtonPushedFcn = createCallbackFcn(app, @NextTurnButtonPushed, true);
            app.NextTurnButton.Text = 'Next Turn';
            app.DamagedealtEditFieldLabel = uilabel(app.RoundsTab);
            app.DamagedealtEditFieldLabel.Text = 'Damage dealt';
            app.DamagedealtEditField = uieditfield(app.RoundsTab, 'numeric');
            app.DamagetargetDropDownLabel = uilabel(app.RoundsTab);
            app.DamagetargetDropDownLabel.Text = 'Damage target';
            app.DamagetargetDropDown = uidropdown(app.RoundsTab);
            app.DamagetargetDropDown.Items = {};
            app.DamagetargetDropDown.Value = {};
            app.RoundEditFieldLabel = uilabel(app.RoundsTab);
            app.RoundEditFieldLabel.Text = 'Round';
            app.RoundEditField = uieditfield(app.RoundsTab, 'numeric');
            app.RoundEditField.Editable = 'off';
            app.ConditiondealtDropDownLabel = uilabel(app.RoundsTab);
            app.ConditiondealtDropDownLabel.Text = 'Condition dealt';
            app.ConditiondealtDropDown = uidropdown(app.RoundsTab);
            app.ConditiondealtDropDown.Items = {'', 'Normal', 'Prone', 'Hiden', 'Grappled', 'Charmed', 'Frightened', 'Incapacitated', 'Paralised', 'Petrified', 'Restrained', 'Stunned', 'Unconscious', 'Poisoned', 'Invisible', 'Blind', 'Deafened'};
            app.ConditiondealtDropDown.ValueChangedFcn = createCallbackFcn(app, @ConditiondealtDropDownValueChanged, true);
            app.ConditiondealtDropDown.Value = '';
            app.TargetDropDown_2Label = uilabel(app.RoundsTab);
            app.TargetDropDown_2Label.Text = 'Target';
            app.TargetDropDown_2 = uidropdown(app.RoundsTab);
            app.TargetDropDown_2.Items = {};
            app.TargetDropDown_2.Value = {};
            app.DamageLabel = uilabel(app.RoundsTab);
            app.DamageLabel.Text = 'Damage';
            app.ConditionLabel = uilabel(app.RoundsTab);
            app.ConditionLabel.Text = 'Condition';
            app.HealLabel = uilabel(app.RoundsTab);
            app.HealLabel.Text = 'Heal';
            app.DamagehealedEditFieldLabel = uilabel(app.RoundsTab);
            app.DamagehealedEditFieldLabel.Text = 'Damage healed';
            app.DamagehealedEditField = uieditfield(app.RoundsTab, 'numeric');
            app.HealtargetDropDownLabel = uilabel(app.RoundsTab);
            app.HealtargetDropDownLabel.Text = 'Heal target';
            app.HealtargetDropDown = uidropdown(app.RoundsTab);
            app.HealtargetDropDown.Items = {};
            app.HealtargetDropDown.Value = {};
            app.DealActionButton = uibutton(app.RoundsTab, 'push');
            app.DealActionButton.ButtonPushedFcn = createCallbackFcn(app, @DealActionButtonPushed, true);
            app.DealActionButton.Text = 'Deal Action';
            % Show the figure after all components are created
            app.CombAlvaTrackedUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CombatTracker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CombAlvaTrackedUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CombAlvaTrackedUIFigure)
        end
    end
end